classdef multicolor19F < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FluorUIFigure               matlab.ui.Figure
        DashboardPanel              matlab.ui.container.Panel
        ImportdataPanel             matlab.ui.container.Panel
        Load19FDataButton           matlab.ui.control.Button
        Lamp1                       matlab.ui.control.Lamp
        MRSolutionsCheckBox         matlab.ui.control.CheckBox
        BrukerCheckBox              matlab.ui.control.CheckBox
        Lamp2                       matlab.ui.control.Lamp
        Load1HDataButton            matlab.ui.control.Button
        UseTestDataCheckBox         matlab.ui.control.CheckBox
        ReconstructionPanel         matlab.ui.container.Panel
        DeconvolutionButton         matlab.ui.control.Button
        Lamp3                       matlab.ui.control.Lamp
        Lamp4                       matlab.ui.control.Lamp
        PseudoInverseButton         matlab.ui.control.Button
        ProgressGaugeLabel          matlab.ui.control.Label
        ProgressGauge               matlab.ui.control.LinearGauge
        ExportdataPanel             matlab.ui.container.Panel
        ExportDicomButton           matlab.ui.control.Button
        ExportGifButton             matlab.ui.control.Button
        Lamp5                       matlab.ui.control.Lamp
        Lamp6                       matlab.ui.control.Lamp
        Panel                       matlab.ui.container.Panel
        ExitButton                  matlab.ui.control.Button
        RawImageDataPanel           matlab.ui.container.Panel
        DataselectionPanel          matlab.ui.container.Panel
        RawNucleusSwitch            matlab.ui.control.Switch
        ReadoutSpinnerLabel         matlab.ui.control.Label
        RawReadoutEditField         matlab.ui.control.Spinner
        ImageselectionPanel         matlab.ui.container.Panel
        RawSliceSlider              matlab.ui.control.Slider
        SliceEditFieldLabel         matlab.ui.control.Label
        SliceEditField              matlab.ui.control.NumericEditField
        OrientationSpinnerLabel     matlab.ui.control.Label
        OrientationSpinner          matlab.ui.control.Spinner
        SliceEditField_2Label_2     matlab.ui.control.Label
        SliceEditField1H            matlab.ui.control.NumericEditField
        RawSliceSlider1H            matlab.ui.control.Slider
        YLabel                      matlab.ui.control.Label
        XLabel                      matlab.ui.control.Label
        RawImagesFig                matlab.ui.control.UIAxes
        AcquisitionparametersPanel  matlab.ui.container.Panel
        FMRIPanel                   matlab.ui.container.Panel
        MatrixEditFieldLabel        matlab.ui.control.Label
        NxViewField                 matlab.ui.control.NumericEditField
        NyViewField                 matlab.ui.control.NumericEditField
        NzViewField                 matlab.ui.control.NumericEditField
        BWHzEditFieldLabel          matlab.ui.control.Label
        BWHzViewField               matlab.ui.control.NumericEditField
        BWpixelHzLabel              matlab.ui.control.Label
        BWpixelViewField            matlab.ui.control.NumericEditField
        FOVyViewField               matlab.ui.control.NumericEditField
        FOVzViewField               matlab.ui.control.NumericEditField
        FOVEditFieldLabel           matlab.ui.control.Label
        FOVxViewField               matlab.ui.control.NumericEditField
        ReadoutsLabel               matlab.ui.control.Label
        NrReadoutsViewField         matlab.ui.control.NumericEditField
        X19FLabel                   matlab.ui.control.Label
        Y19FLabel                   matlab.ui.control.Label
        Z19FLabel                   matlab.ui.control.Label
        HMRIPanel                   matlab.ui.container.Panel
        Ny1HViewField               matlab.ui.control.NumericEditField
        Nz1HViewField               matlab.ui.control.NumericEditField
        FOVy1HViewField             matlab.ui.control.NumericEditField
        FOVz1HViewField             matlab.ui.control.NumericEditField
        FOV1HViewFieldLabel         matlab.ui.control.Label
        FOVx1HViewField             matlab.ui.control.NumericEditField
        MatrixEditField_2Label      matlab.ui.control.Label
        Nx1HViewField               matlab.ui.control.NumericEditField
        TRmsLabel                   matlab.ui.control.Label
        TR1HViewField               matlab.ui.control.NumericEditField
        TEmsLabel                   matlab.ui.control.Label
        TE1HViewField               matlab.ui.control.NumericEditField
        X1HLabel                    matlab.ui.control.Label
        Y1HLabel                    matlab.ui.control.Label
        Z1HLabel                    matlab.ui.control.Label
        VersionLabel                matlab.ui.control.Label
        ReconstructionsPanel        matlab.ui.container.Panel
        C1Fig                       matlab.ui.control.UIAxes
        C2Fig                       matlab.ui.control.UIAxes
        ImageSelectionPanel         matlab.ui.container.Panel
        RawSliceSlider2             matlab.ui.control.Slider
        SliceEditField_2Label       matlab.ui.control.Label
        SliceEditField2             matlab.ui.control.NumericEditField
        OrientationSpinner_2Label   matlab.ui.control.Label
        OrientationSpinner2         matlab.ui.control.Spinner
        ThresholdPanel              matlab.ui.container.Panel
        ThresholdEditFieldLabel     matlab.ui.control.Label
        ThresholdEditField          matlab.ui.control.NumericEditField
        ThresholdAutoButton         matlab.ui.control.Button
        ThresholdSlider             matlab.ui.control.Slider
        CompoundSwitch              matlab.ui.control.Switch
        ColormapButtonGroup         matlab.ui.container.ButtonGroup
        grayButton                  matlab.ui.control.RadioButton
        fluorButton                 matlab.ui.control.RadioButton
        hotButton                   matlab.ui.control.RadioButton
        parulaButton                matlab.ui.control.RadioButton
        summerButton                matlab.ui.control.RadioButton
        winterButton                matlab.ui.control.RadioButton
        SparseDeconvolutionPanel    matlab.ui.container.Panel
        ParametersPanel             matlab.ui.container.Panel
        ZeroFillCheckBox            matlab.ui.control.CheckBox
        TransCorrCheckBox           matlab.ui.control.CheckBox
        ZeroFillLabel               matlab.ui.control.Label
        TransCorrLabel              matlab.ui.control.Label
        LambdaEditFieldLabel        matlab.ui.control.Label
        LambdaEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel    matlab.ui.control.Label
        IterationsEditField         matlab.ui.control.NumericEditField
        GPUCheckBox                 matlab.ui.control.CheckBox
        GPULabel                    matlab.ui.control.Label
        BlindDeconvolutionCheckBox  matlab.ui.control.CheckBox
        BlinddeconvLabel            matlab.ui.control.Label
        FcompoundsLabel             matlab.ui.control.Label
        CompoundBox                 matlab.ui.control.TextArea
        DeconvolutionspectraPanel   matlab.ui.container.Panel
        C1SpectrumFig               matlab.ui.control.UIAxes
        C1SpectrumLabel             matlab.ui.control.Label
        C2SpectrumFig               matlab.ui.control.UIAxes
        C2SpectrumLabel             matlab.ui.control.Label
        TitlePanel                  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        MessagesPanel               matlab.ui.container.Panel
        MessageBox                  matlab.ui.control.TextArea
    end


    % ---------------------------------------------------------------------
    %
    %       Two-color 19F-MRI reconstruction
    %
    %       Jasper Schoormans, Gustav Strijkers
    %       2019
    %
    %       According to :
    %       Schoormans, J., Calcagno, C., Daal, M., Wüst, R., Faries, C., Maier, A., Teunissen, A., Naidu, S.,
    %       Sanchez‐Gaytan, B., Nederveen, A., Fayad, Z., Mulder, W., Coolen, B., Strijkers, G.
    %       An iterative sparse deconvolution method for simultaneous multicolor 19F‐MRI of multiple contrast agents
    %       Magnetic Resonance in Medicine 2020, 83(1), 228-239. https://dx.doi.org/10.1002/mrm.27926
    %
    %       Version 1.0
    %       - First version
    %
    %
    %       Version 1.1
    %       - Bruker implementation = sequence FLASH_MP19F.ppg (Gustav Strijkers) for Paravision 5.1
    %       - compound files: compound1.txt, compound2.txt
    %       -
    %
    %
    %
    %
    %
    %
    %
    %


    properties (Access = public)
        appdir                      % Application directory
        olddir                      % Starting directory
        data_import_path            % data import path
        data_import_path_test_19F   % relative path of testdata for 19F
        data_import_path_test_1H   % relative path of testdata for 1H
        brukerinfo1                 % Bruker data info
        brukerinfo2                 % Bruker data info
        dicom_export_path           % Dicom export path
        gif_export_path             % gif export path
        bart_detected               % Bart available true or false
        valid19Ffile                % valid 19F file loaded true or false
        valid1Hfile                 % valid 1H file loaded true or false
        R                           % reconstruction structure
        recodata                    % reconstructed 19F images
        directions                  % name of the directions (HF, FH, LR, RL, AP, PA)
        image19F_orient             % 19F image orientation (for viewer in app only)
        image1H_orient              % 1H image orientation (for viewer in app only)
        image_orient_label          % image orientation label (for viewer in app only)
        mask                        % threshold mask
        threshold                   % threshold values
        scalemax                    % max image intensity (for viewing in app)
        cmap                        % colormap for 19F images
        cindx                       % index for colormaps
        FOV19F                      % 19F field of view in mm
        matrix19F                   % 19F matrix size
        image19F                    % 19F raw images with chemical shift artefacts
        TR19F                       % 19F repetition time
        TE19F                       % 19F echo time
        NA19F                       % 19F number of averages
        alpha19F                    % 19F flip angle
        FOV1H                       % 1H field of view in mm
        FOVmatch                    % matching FOVs true or false
        TR1H                        % 1H repetition time
        TE1H                        % 1H echo time
        NA1H                        % 1H number of averages
        alpha1H                     % 1H flip angle
        alias1H                     % 1H aliasing factors
        data1H                      % 1H data structure
        matrix1H                    % 1H reconstruction matrix size
        image1H                     % 1H reconstructed images
        status                      % user interface status
        tag                         % Dicom export tag
        seqpar                      % Sequence parameters
        c1name                      % Name of compound 1 (e.g. PFCE)
        c2name                      % Name of compound 2 (e.g. PFOB)
        c1data                      % Compound 1 peak positions and intensities
        c2data                      % Compound 2 peak positions and intensities
        
    end
    
    methods (Access = private)
        
        
        % ------------------------------------------
        % Lights and buttons
        % ------------------------------------------
        
        
        function SetLights(app,code)
            
            if ~strcmp(code{1},'Null') app.Lamp1.Color = code{1}; end
            if ~strcmp(code{2},'Null') app.Lamp2.Color = code{2}; end
            if ~strcmp(code{3},'Null') app.Lamp3.Color = code{3}; end
            if ~strcmp(code{4},'Null') app.Lamp4.Color = code{4}; end
            if ~strcmp(code{5},'Null') app.Lamp5.Color = code{5}; end
            if ~strcmp(code{6},'Null') app.Lamp6.Color = code{6}; end
            drawnow;
            
        end
        
        
        function result = GetLights(app)
            
            result{1} = app.Lamp1.Color;
            result{2} = app.Lamp2.Color;
            result{3} = app.Lamp3.Color;
            result{4} = app.Lamp4.Color;
            result{5} = app.Lamp5.Color;
            result{6} = app.Lamp6.Color;
            
        end
        
        
        function SetButtons(app,code)
            
            if ~strcmp(code{1},'Null') app.Load19FDataButton.Enable = code{1}; end
            if ~strcmp(code{2},'Null') app.Load1HDataButton.Enable = code{2}; end
            if ~strcmp(code{3},'Null') app.PseudoInverseButton.Enable = code{3}; end
            if ~strcmp(code{4},'Null') app.DeconvolutionButton.Enable = code{4}; end
            if ~strcmp(code{5},'Null') app.ExportDicomButton.Enable = code{5}; end
            if ~strcmp(code{6},'Null') app.ExportGifButton.Enable = code{6}; end
            if ~strcmp(code{7},'Null') app.ExitButton.Enable = code{7}; end
            drawnow;
            
        end
        
        
        function result = GetButtons(app)
            
            result{1} = app.Load19FDataButton.Enable;
            result{2} = app.Load1HDataButton.Enable;
            result{3} = app.PseudoInverseButton.Enable;
            result{4} = app.DeconvolutionButton.Enable;
            result{5} = app.ExportDicomButton.Enable;
            result{6} = app.ExportGifButton.Enable;
            result{7} = app.ExitButton.Enable;
            
        end
        
        
        function EnableEditFields(app)
            
            app.ZeroFillCheckBox.Enable = 'on';
            app.TransCorrCheckBox.Enable = 'on';
            app.LambdaEditField.Enable = 'on';
            app.IterationsEditField.Enable = 'on';
            app.GPUCheckBox.Enable = 'on';
            app.BlindDeconvolutionCheckBox.Enable = 'on';
            drawnow;
            
        end
        
        
        function DisableEditFields(app)
            
            app.ZeroFillCheckBox.Enable = 'off';
            app.TransCorrCheckBox.Enable = 'off';
            app.LambdaEditField.Enable = 'off';
            app.IterationsEditField.Enable = 'off';
            app.GPUCheckBox.Enable = 'off';
            app.BlindDeconvolutionCheckBox.Enable = 'off';
            drawnow;
            
        end
        
        
        % ------------------------------------------
        % Parameters
        % ------------------------------------------
        
        
        function SetCommonParametersFcn(app)
            
            % Common parameters     19F:  [1, 2, 3] = [Z, X, Y]        1H:[1, 2, 3] = [X, Y, Z]
            app.image19F_orient = [1 3 2 ; 2 3 1 ; 3 2 1];
            app.image1H_orient  = [3 2 1 ; 1 2 3 ; 2 3 1];
            
            app.image_orient_label = ["Y" "X" ; "Y" "Z" ; "Z" "X"];
            
            app.threshold{1} = 0;
            app.threshold{2} = 0;
            app.cmap{1} = gray;
            app.cindx{1} = 1;
            app.cmap{2} = gray;
            app.cindx{2} = 1;
            app.scalemax = 4095;
            
        end
        
        
        function ReadCompoundsFcn(app)
            
            % Reading the compound names, peak intensitities, and peak positions from file
            fid = fopen('compound1.txt');
            app.c1name = fgetl(fid);
            fclose(fid);
            app.c1data = dlmread('compound1.txt',' ',1,0);
            
            fid = fopen('compound2.txt');
            app.c2name = fgetl(fid);
            fclose(fid);
            app.c2data = dlmread('compound2.txt',' ',1,0);
            
            % Set compound names in app
            app.C1SpectrumLabel.Text = app.c1name;
            app.C2SpectrumLabel.Text = app.c2name;
            app.CompoundSwitch.Items = {app.c1name,app.c2name};
            
            
            % Show them in the app window
            compounds = [
                convertCharsToStrings(app.c1name) ; ...
                strcat("I   (a.u.) :  ",num2str(app.c1data(1:end,1)','    %3.1f')) ; ...
                strcat("δ (ppm) :  ",num2str(app.c1data(1:end,2)','    %3.1f')) ; ...
                "   " ; ...
                convertCharsToStrings(app.c2name) ; ...
                strcat("I   (a.u.) :  ",num2str(app.c2data(1:end,1)','    %3.1f')) ; ...
                strcat("δ (ppm) :  ",num2str(app.c2data(1:end,2)','   %3.1f'))
                ];
            app.CompoundBox.Value = compounds;
            drawnow;
            
        end


        function SetParametersFcn(app)
            
            if app.valid19Ffile
                
                % Initial parameters
                app.R.P.ZeroFill = app.ZeroFillCheckBox.Value;
                app.R.P.TranslationCorr = app.TransCorrCheckBox.Value;
                app.R.P.phaseremoval = 1;    % removes the phase of the 19F images, absolute spectrum can be used
                app.R.P.useGPU = app.GPUCheckBox.Value;
                app.R.P.lambda = app.LambdaEditField.Value;
                app.R.P.niter = app.IterationsEditField.Value;
                app.R.P.use_empirical_alpha = 1;
                app.R.Data.improton = [];
                
                % Spectra from compound1 and compound2 files
                app.R.P.empirical_alpha1 = flip(app.c1data(1:end,1)');
                app.R.P.empirical_ppm1 = flip(app.c1data(1:end,2)');
                app.R.P.empirical_alpha2 = flip(app.c2data(1:end,1)');
                app.R.P.empirical_ppm2 = flip(app.c2data(1:end,2)');
                
                % Update parameters
                app.R=app.R.UpdateP;
                
                % Show the relevant parameters in the app
                app.NxViewField.Value = app.R.P.nx;
                app.NyViewField.Value = app.R.P.ny;
                app.NzViewField.Value = app.R.P.nz;
                app.matrix19F = [app.R.P.nx,app.R.P.ny,app.R.P.nz];
                app.BWHzViewField.Value = round(app.R.P.BW);
                app.BWpixelViewField.Value = app.R.P.BWpix;
                app.RawReadoutEditField.Limits = [1 app.R.P.nacq];
                app.SliceEditField.Value = 1;
                app.RawSliceSlider.Value = 1;
                app.SliceEditField2.Value = 1;
                app.RawSliceSlider2.Value = 1;
                app.SliceEditField.Limits = [1 app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))];
                app.RawSliceSlider.Limits = [1 app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))];
                app.SliceEditField2.Limits = [1 app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))];
                app.RawSliceSlider2.Limits = [1 app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))];
                app.SliceEditField.Value = round(app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))/2);
                app.RawSliceSlider.Value = round(app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))/2);
                app.SliceEditField2.Value = round(app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))/2);
                app.RawSliceSlider2.Value = round(app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))/2);
                app.FOVxViewField.Value = app.FOV19F(1);
                app.FOVyViewField.Value = app.FOV19F(2);
                app.FOVzViewField.Value = app.FOV19F(3);
                
                % Raw image data with chemical shift artefacts
                app.image19F = app.R.Data.K;
                
                for i=1:app.R.P.Seq.ndirs
                    
                    im = app.image19F{i};
                    
                    % FFT
                    im = abs(fft3reco(im));
                    
                    % Crop to remove oversampling
                    [dmx,dmy,dmz] = size(im);
                    im = im(dmx/2 - app.R.P.nx/2 + 1 : dmx/2 + app.R.P.nx/2, dmy/2 - app.R.P.ny/2 + 1 : dmy/2 + app.R.P.ny/2, dmz/2 - app.R.P.nz/2 + 1 : dmz/2 + app.R.P.nz/2);
                    
                    app.image19F{i} = im;
                    app.image19F{i} = permute(app.image19F{i},[3,2,1]);
                    
                end
                
                % Reconstructions of 19F images
                app.recodata{1} = squeeze(app.R.Recon{2,1});
                app.recodata{2} = squeeze(app.R.Recon{2,2});
                
                % 19F Masks
                app.mask = ones(2,app.R.P.nx,app.R.P.ny,app.R.P.nz);
                
                % Show initial 19F spectra
                CalculateSpectraFcn(app);
                
            end
            
        end % SetParametersFcn
        
        
        function Set1HParametersFcn(app)
            
            if app.valid1Hfile
                
                % Set the relevant sequence parameters in the app
                app.FOVx1HViewField.Value = app.FOV1H(1);
                app.FOVy1HViewField.Value = app.FOV1H(2);
                app.FOVz1HViewField.Value = app.FOV1H(3);
                
                app.Nx1HViewField.Value = app.matrix1H(1);
                app.Ny1HViewField.Value = app.matrix1H(2);
                app.Nz1HViewField.Value = app.matrix1H(3);
                
                app.TR1HViewField.Value = app.TR1H;
                app.TE1HViewField.Value = app.TE1H;
                
                % Set values for the 1H slice selector
                app.SliceEditField1H.Value = 1;
                app.RawSliceSlider1H.Value = 1;
                app.SliceEditField1H.Limits = [1 app.matrix1H(app.image1H_orient(app.OrientationSpinner.Value,1))];
                app.RawSliceSlider1H.Limits = [1 app.matrix1H(app.image1H_orient(app.OrientationSpinner.Value,1))];
                app.SliceEditField1H.Value = round(app.matrix1H(app.image1H_orient(app.OrientationSpinner.Value,1))/2);
                app.RawSliceSlider1H.Value = round(app.matrix1H(app.image1H_orient(app.OrientationSpinner.Value,1))/2);
                
            end
            
        end % Set1HParametersFcn
        
        
        % ------------------------------------------
        % Spectra and figures
        % ------------------------------------------
        
        
        function CalculateSpectraFcn(app)
            
            % Calculate 19F compound spectrum
            app.R = app.R.CalculateSpectra;
            C1spectrum = app.R.P.Spectrum1;
            C2spectrum = app.R.P.Spectrum2;
            
            % Plot C1 spectrum
            cla(app.C1SpectrumFig);
            hold(app.C1SpectrumFig,'on');
            plot(app.C1SpectrumFig,fftshift(abs(C1spectrum)));
            xlim(app.C1SpectrumFig,[1 length(C1spectrum)]);
            ylim(app.C1SpectrumFig,[0 1.25*max(C1spectrum(:))]);
            hold(app.C1SpectrumFig,'off');
            
            % Plot C2 spectrum
            cla(app.C2SpectrumFig);
            hold(app.C2SpectrumFig,'on');
            plot(app.C2SpectrumFig,fftshift(abs(C2spectrum)));
            xlim(app.C2SpectrumFig,[1 length(C2spectrum)]);
            ylim(app.C2SpectrumFig,[0 1.25*max(abs(C2spectrum(:)))]);
            hold(app.C2SpectrumFig,'off');
            
            drawnow;
            
        end % CalculateSpectraFcn
        
        
        function ShowAllFigures(app)
            
            % Plot all figures in the app
            PlotRawDataFig(app);
            
            if app.valid19Ffile
                PlotC2Fig(app,app.recodata{1}.*squeeze(app.mask(1,:,:,:)));
                PlotC1Fig(app,app.recodata{2}.*squeeze(app.mask(2,:,:,:)));
            end
            
        end % ShowAllFigures


        function result = CalcAspectratioFnc1H(app,dim1label,dim2label,sizex,sizey)
            
            if strcmp(dim1label,'X') L1 = app.FOVx1HViewField.Value; end
            if strcmp(dim1label,'Y') L1 = app.FOVy1HViewField.Value; end
            if strcmp(dim1label,'Z') L1 = app.FOVz1HViewField.Value; end
            
            if strcmp(dim2label,'X') L2 = app.FOVx1HViewField.Value; end
            if strcmp(dim2label,'Y') L2 = app.FOVy1HViewField.Value; end
            if strcmp(dim2label,'Z') L2 = app.FOVz1HViewField.Value; end
            
            result = (L1/L2)*(sizex/sizey);
            
        end % Aspectratio
        
        
        function result = CalcAspectratioFnc19F(app,dim1label,dim2label,sizex,sizey)
            
            if strcmp(dim1label,'X') L1 = app.FOVxViewField.Value; end
            if strcmp(dim1label,'Y') L1 = app.FOVyViewField.Value; end
            if strcmp(dim1label,'Z') L1 = app.FOVzViewField.Value; end
            
            if strcmp(dim2label,'X') L2 = app.FOVxViewField.Value; end
            if strcmp(dim2label,'Y') L2 = app.FOVyViewField.Value; end
            if strcmp(dim2label,'Z') L2 = app.FOVzViewField.Value; end
            
            result = (L1/L2)*(sizex/sizey);
            
        end % Aspectratio


        function PlotRawDataFig(app)
            
            hold(app.RawImagesFig,'on');
            
            switch app.RawNucleusSwitch.Value
                
                case '19F'
                    
                    if app.valid19Ffile
                        
                        % Extract the data
                        im = app.image19F{app.RawReadoutEditField.Value};
                        
                        % Estimate a suitable scale
                        scale = round(max(nonzeros(im(:))));
                        if isnan(scale) scale = 100; end
                        
                        % Select which slice to show
                        im = permute(im,app.image19F_orient(app.OrientationSpinner.Value,:));
                        im = squeeze(im(app.SliceEditField.Value,:,:));
                        
                        % Label the orientations
                        app.XLabel.Text = app.image_orient_label(app.OrientationSpinner.Value,1);
                        app.YLabel.Text = app.image_orient_label(app.OrientationSpinner.Value,2);
                        
                        % Plot the image in the raw images panel
                        app.RawImagesFig.Position = [31,44,230,230];
                        
                        imshow(rot90(im),[0 scale],'Parent',app.RawImagesFig);
                        xlim(app.RawImagesFig,[0, size(im,2)+1]);
                        ylim(app.RawImagesFig,[0, size(im,1)+1]);
                        aspectr = app.CalcAspectratioFnc19F(app.XLabel.Text,app.YLabel.Text,size(im,2),size(im,1));
                        daspect(app.RawImagesFig,[1 aspectr 1]);
                        
                    end
                    
                case '1H'
                    
                    if app.valid1Hfile
                        
                        % Select the data
                        im = app.image1H;
                        
                        % Estimate a suitable scale
                        scale = round(max(nonzeros(im(:))));
                        if isnan(scale) scale = 100; end
                        
                        % Select which slice to show
                        im = permute(im,app.image1H_orient(app.OrientationSpinner.Value,:));
                        im = squeeze(im(app.SliceEditField1H.Value,:,:));
                        
                        % Label the orientations
                        app.XLabel.Text = app.image_orient_label(app.OrientationSpinner.Value,1);
                        app.YLabel.Text = app.image_orient_label(app.OrientationSpinner.Value,2);
                        
                        % Plot the image in the raw images panel
                        app.RawImagesFig.Position = [31,44,230,230];
                        
                        imshow(rot90(im),[0 scale],'InitialMagnification','fit','Parent',app.RawImagesFig);
                        xlim(app.RawImagesFig,[0, size(im,1)+1]);
                        ylim(app.RawImagesFig,[0, size(im,2)+1]);
                        aspectr = app.CalcAspectratioFnc1H(app.XLabel.Text,app.YLabel.Text,size(im,2),size(im,1));
                        daspect(app.RawImagesFig,[1 aspectr 1]);
                        
                        
                    end
                    
            end
            
            hold(app.RawImagesFig,'off');
            drawnow;
            
        end % PlotRawDataFig


        function PlotC2Fig(app, im)
            
            cla(app.C2Fig);
            hold(app.C2Fig,'on');
            
            % Select which slice to show
            im0 = permute(abs(im),[3,2,1]);
            im = permute(im0,app.image19F_orient(app.OrientationSpinner.Value,:));
            im = squeeze(im(app.SliceEditField.Value,:,:));
            
            if app.valid1Hfile & app.FOVmatch
                % Plot 1H and 19F C2 image as background and overlay
                
                % Select 1H image and resize to 19F size
                im1 = imresize3(app.image1H,size(im0));
                im1 = permute(im1,app.image1H_orient(app.OrientationSpinner.Value,:));
                im1 = squeeze(im1(app.SliceEditField.Value,:,:));
                scale1 = max(im1(:));
                
                % Define the colormap
                % Since it is not possible to have 2 colormaps in 1 figure, the
                % colormap is split in a grayscale part [0 1] and a color overlay part [1 2]
                cmap1 = gray;
                cmap2 = app.cmap{1};
                cmapo = [cmap1;cmap2];
                
                % 1H background image
                app.C2Fig.Position = [270,128,230,230];
                xlim(app.C2Fig,[1, size(im1,2)]);
                ylim(app.C2Fig,[1, size(im1,1)]);
                im1scaled = rot90(im1)/(1.5*scale1);
                im1scaled(im1scaled > 0.99) = 0.99;
                background = imshow(im1scaled,[0 2],'Colormap',cmapo,'InitialMagnification','fit','Parent',app.C2Fig);
                
                % PFCE overlay image
                overlay = imshow(1 + rot90(im)/app.scalemax,[0 2],'Colormap',cmapo,'InitialMagnification','fit','Parent',app.C2Fig);
                overlaymask = rot90(im);
                overlaymask(overlaymask>0)=1;
                set(overlay,'AlphaData',overlaymask);
                
            else
                % Plot the C2 image only
                
                app.C2Fig.Position = [270,128,230,230];
                xlim(app.C2Fig,[1, size(im,2)]);
                ylim(app.C2Fig,[1, size(im,1)]);
                imshow(rot90(im),[0 app.scalemax],'Colormap',app.cmap{1},'InitialMagnification','fit','Parent',app.C2Fig);
                
            end
            
            hold(app.C2Fig,'off');
            drawnow;
            
        end % PlotC2Fig


        function PlotC1Fig(app, im)
            
            cla(app.C1Fig);
            hold(app.C1Fig,'on');
            
            % Select which 19F C1 slice to show
            im0 = permute(abs(im),[3,2,1]);
            im = permute(im0,app.image19F_orient(app.OrientationSpinner.Value,:));
            im = squeeze(im(app.SliceEditField.Value,:,:));
            
            if app.valid1Hfile & app.FOVmatch
                % Plot 1H and 19F C1 image as background and overlay
                
                % Select 1H image and resize to 19F size
                im1 = imresize3(app.image1H,size(im0));
                im1 = permute(im1,app.image1H_orient(app.OrientationSpinner.Value,:));
                im1 = squeeze(im1(app.SliceEditField.Value,:,:));
                scale1 = max(im1(:));
                
                % Define the colormap
                % Since it is not possible to have 2 colormaps in 1 figure, the
                % colormap is split in a grayscale part [0 1] and a color overlay part [1 2]
                cmap1 = gray;
                cmap2 = app.cmap{2};
                cmapo = [cmap1;cmap2];
                
                % 1H background image
                app.C1Fig.Position = [25,128,230,230];
                xlim(app.C1Fig,[1, size(im1,2)]);
                ylim(app.C1Fig,[1, size(im1,1)]);
                im1scaled = rot90(im1)/(1.5*scale1);
                im1scaled(im1scaled > 0.99) = 0.99;
                background = imshow(im1scaled,[0 2],'Colormap',cmapo,'InitialMagnification','fit','Parent',app.C1Fig);
                
                % C1 overlay image
                overlay = imshow(1 + rot90(im)/app.scalemax,[0 2],'Colormap',cmapo,'InitialMagnification','fit','Parent',app.C1Fig);
                overlaymask = rot90(im);
                overlaymask(overlaymask>0)=1;
                set(overlay,'AlphaData',overlaymask);
                
            else
                % Plot the C1 image only
                
                app.C1Fig.Position = [25,128,230,230];
                xlim(app.C1Fig,[1, size(im,2)]);
                ylim(app.C1Fig,[1, size(im,1)]);
                imshow(rot90(im),[0 app.scalemax],'Colormap',app.cmap{2},'InitialMagnification','fit','Parent',app.C1Fig);
                
            end
            
            hold(app.C1Fig,'off');
            drawnow;
            
        end % PlotC1Fig


        function ApplyThreshold(app)
            
            if app.valid19Ffile
                
                % Caluclates the binary masks for 19F images, based on the app.threshold values
                app.mask(1,:,:,:) = imbinarize(mat2gray(app.recodata{1}),app.threshold{1}/max(app.recodata{1}(:)));
                app.mask(2,:,:,:) = imbinarize(mat2gray(app.recodata{2}),app.threshold{2}/max(app.recodata{2}(:)));
                
            end
            
        end % ApplyThreshold
        
        
    end
    
    
    % ------------------------------------------
    % Public functions (messages, gauges, fitprogress)
    % ------------------------------------------
    
    
    methods (Access = public)
        
        
        function TextMessage(app, message)
            
            % Text messages in the app
            app.MessageBox.Value = [message ; app.MessageBox.Value];
            drawnow;
            
        end % TextMessage
        
        
        function ShowReco(app, imagedata)
            
            % Show intermediate result of the fit
            img1 = squeeze(imagedata(:,:,:,1));
            img1 = 4095*img1/max(img1(:));
            
            img2 = squeeze(imagedata(:,:,:,2));
            img2 = 4095*img2/max(img2(:));
            
            PlotC2Fig(app, img1);
            PlotC1Fig(app, img2);
            
        end % ShowReco
        
        
        function ShowFitProgress(app, progress)
            
            % Update the progress bar
            app.ProgressGauge.Value = progress;
            
        end % ShowFitProgress
        
        
    end
    
    
    % ------------------------------------------
    % Callback functions
    % ------------------------------------------


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % empty file import and export paths
            app.data_import_path = '';
            app.dicom_export_path = '';
            app.gif_export_path = '';
            
            % add subfolders to search path
            if (~isdeployed)
                
                app.olddir = pwd;
                info = dir(which('multicolor19F.mlapp'));
                app.appdir = info.folder;
                cd(app.appdir);
                addpath(genpath(pwd));
                
            end
            
            % add the bart path to the environment variable if available
            app.bart_detected = false;
            
            if isfile('bartpath.txt')
                
                app.bart_detected = true;
                setenv('TOOLBOX_PATH', fileread('bartpath.txt'));
                
            end
            
            % set location of testdata
            app.data_import_path_test_19F = 'tests\testdata\MP\7\';          % standard data (fully sampled)
            app.data_import_path_test_1H = 'tests\testdata\1H\2\';
            
            % suppress unneccesary warnings
            warning off;
            
        end

        % Value changed function: MRSolutionsCheckBox
        function MRSolutionsCheckBoxValueChanged(app, event)
            
            % set data format to MR Solutions
            app.BrukerCheckBox.Value = 0;
            app.MRSolutionsCheckBox.Value = 1;
            
        end

        % Value changed function: BrukerCheckBox
        function BrukerCheckBoxValueChanged(app, event)
            
            % set data format to Bruker
            app.BrukerCheckBox.Value = 1;
            app.MRSolutionsCheckBox.Value = 0;
            
        end

        % Button pushed function: Load19FDataButton
        function Load19FDataButtonPushed(app, event)
            
            % Indicate busy
            SetLights(app,{'Yellow','Null','Null','Null','Null','Null'});
            SetButtons(app,{'off','off','off','off','off','off','off'});
            
            % Reading the compound names, peak intensitities, and peak positions from file
            app.ReadCompoundsFcn;
            
            % Reading the actual data
            app.valid19Ffile = false;
            
            if app.BrukerCheckBox.Value == 1
                
                % read bruker data, select a FID file
                app.FluorUIFigure.Visible = 'off';
                
                if app.UseTestDataCheckBox.Value == 1
                    TextMessage(app, app.data_import_path_test_19F);
                    TextMessage(app, 'Using 19F Test Data:');
                    datafile = 'fid';
                    app.data_import_path = app.data_import_path_test_19F;
                else
                    [datafile,app.data_import_path] = uigetfile([app.data_import_path,filesep,'*.*'],'Select an FID file');
                end
                
                
                app.FluorUIFigure.Visible = 'on';
                
                
                if strcmp(datafile,'fid')
                    
                    app.valid19Ffile = true;
                    clear app.R;
                    app.R = FluorRecon({app.data_import_path});
                    
                    % Let the R object know that the app exists
                    app.R.app = app;
                    
                    % Read bruker specific sequence information
                    app.brukerinfo1 = jcampread([app.data_import_path,'method']);
                    app.brukerinfo2 = jcampread([app.data_import_path,'acqp']);
                    
                    app.R.P.BW = str2num(app.brukerinfo1.pvm.effswh);               % Readout bandwidth
                    app.R.P.ppm = str2num(app.brukerinfo2.BF1);                     % ppm
                    app.TR19F = str2num(app.brukerinfo1.pvm.repetitiontime);        % repetition time
                    app.TE19F = str2num(app.brukerinfo1.pvm.echotime);              % echo time
                    app.alpha19F = str2num(app.brukerinfo1.pvm.excpulseangle);      % flip angle
                    app.FOV19F = app.brukerinfo1.pvm.fov;                           % field of view
                    app.NA19F = str2num(app.brukerinfo1.pvm.naverages);             % number of averages
                    
                    % Only single data file with all (usually 4) directions supported
                    app.R.P.Seq.Sequential = 1; % single data file
                    app.R.P.Seq.ndirs = str2num(app.brukerinfo2.NI);
                    
                    if app.R.P.Seq.ndirs < 2
                        
                        TextMessage(app, 'ERROR: Data does not contain multiple readout directions');
                        app.valid19Ffile = false;
                        
                    else
                        
                        app.NrReadoutsViewField.Value = app.R.P.Seq.ndirs;
                        app.R.P.directions = {'HF','FH','LR','RL'};
                        
                        if strcmp(app.brukerinfo1.Method,'fLASH_MPCS_19F')
                            % read undersampled Bruker data
                            app.R = app.R.loadBrukerFilesCS;
                        else
                            % read fully sampled Bruker data
                            app.R = app.R.loadBrukerFiles;
                        end
                        
                        % set other parameters
                        SetCommonParametersFcn(app);
                        SetParametersFcn(app);
                        
                        % show all figures
                        app.RawNucleusSwitch.Value = '19F';
                        RawNucleusSwitchValueChanged(app, event);
                        ShowAllFigures(app);
                        
                    end
                    
                end
                
            else
                
                % MR Solutions data not implemented yet
                
            end
            
            
            % Report on valid/invalid file selection
            if app.valid19Ffile
                
                % Set the buttons for valid 19F file
                SetLights(app,{'Green','Null','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','on','on','off','off','on'});
                EnableEditFields(app);
                
            else
                
                % Not a valid data file
                uialert(app.FluorUIFigure,'Please select a valid data file','Error');
                app.data_import_path = '';
                
                % Set the buttons for not a valid data file
                SetLights(app,{'Red','Null','Red','Red','Red','Red'});
                SetButtons(app,{'on','on','off','off','off','off','on'});
                DisableEditFields(app);
                
            end
            
        end

        % Button pushed function: Load1HDataButton
        function Load1HDataButtonPushed(app, event)
            
            % Indicate busy
            buttonstatus = GetButtons(app);
            SetLights(app,{'Null','Yellow','Null','Null','Null','Null'});
            SetButtons(app,{'off','off','off','off','off','off','off'});
            
            app.valid1Hfile = false;
            
            if app.BrukerCheckBox.Value == 1
                
                % Read bruker data, select a FID file
                app.FluorUIFigure.Visible = 'off';
                
                if app.UseTestDataCheckBox.Value == 1
                    TextMessage(app, app.data_import_path_test_1H);
                    TextMessage(app, 'Using 1H Test Data:');
                    datafile = 'fid';
                    app.data_import_path = app.data_import_path_test_1H;
                else
                    
                    [datafile,app.data_import_path] = uigetfile([app.data_import_path,filesep,'*.*'],'Select an FID file');
                    
                end
                
                app.FluorUIFigure.Visible = 'on';
                
                if strcmp(datafile,'fid')
                     
                    app.valid1Hfile = true;
                    
                    % Read bruker specific sequence information
                    brukerinfo1p = jcampread([app.data_import_path,'method']);
                    brukerinfo2p = jcampread([app.data_import_path,'acqp']);
                    
                    app.FOV1H = brukerinfo1p.pvm.fov;                        % field of view
                    app.TR1H = str2num(brukerinfo1p.pvm.repetitiontime);     % repetition time
                    app.TE1H = str2num(brukerinfo1p.pvm.echotime);           % echo time
                    app.alpha1H = str2num(brukerinfo1p.pvm.excpulseangle);   % flip angle
                    app.NA1H = str2num(brukerinfo1p.pvm.naverages);          % number of averages
                    
                    % Read the Bruker data
                    pathparts = strsplit(app.data_import_path, filesep);
                    basepath = strjoin(pathparts(1:end-2),filesep);
                    app.data1H = PV3read(basepath,str2num(pathparts{end-1}));
                    app.image1H = app.data1H{1,1}.Scan{1,1}.Reconstruction{1,1}.images;         % the reconstructed images
                    %app.image1H = permute(app.image1H,[3,1,2]);
                    app.image1H = permute(app.image1H,[1,2,3]);
                    app.matrix1H = size(app.image1H);                                           % reconstruction matrix size
                    
                    % Set other parameters
                    SetCommonParametersFcn(app);
                    Set1HParametersFcn(app);
                    
                    % Show all figures
                    app.RawNucleusSwitch.Value = '1H';
                    RawNucleusSwitchValueChanged(app, event);
                    ShowAllFigures(app);
                    
                end
                
            else
                
                % MR Solutions data not implemented yet
                
            end
            
            % Report on valid/invalid file selection
            if app.valid1Hfile
                
                % Set the buttons for valid 1H file
                SetLights(app,{'Null','Green','Null','Null','Null','Null'});
                SetButtons(app,buttonstatus);
                
            else
                
                % Not a valid data file
                uialert(app.FluorUIFigure,'Please select a valid data file','Error');
                app.data_import_path = '';
                
                % Set the buttons for not a valid data file
                SetLights(app,{'Null','Red','Null','Null','Null','Null'});
                SetButtons(app,buttonstatus);
                
            end
            
        end

        % Value changed function: RawNucleusSwitch
        function RawNucleusSwitchValueChanged(app, event)
            
            drawnow;
            
            switch app.RawNucleusSwitch.Value
                
                case '19F'
                    
                    % Make the 19F slice selectors visible
                    app.RawSliceSlider1H.Visible = 'off';
                    app.SliceEditField1H.Visible = 'off';
                    app.RawSliceSlider.Visible = 'on';
                    app.RawSliceSlider.Visible = 'on';
                    
                    if app.valid19Ffile
                        
                        % Set appropriate bounds for the slice selectors
                        app.SliceEditField.Value = 1;
                        app.RawSliceSlider.Value = 1;
                        app.SliceEditField2.Value = 1;
                        app.RawSliceSlider2.Value = 1;
                        app.SliceEditField.Limits = [1 app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))];
                        app.RawSliceSlider.Limits = [1 app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))];
                        app.SliceEditField2.Limits = [1 app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))];
                        app.RawSliceSlider2.Limits = [1 app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))];
                        app.SliceEditField.Value = round(app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))/2);
                        app.RawSliceSlider.Value = round(app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))/2);
                        app.SliceEditField2.Value = round(app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))/2);
                        app.RawSliceSlider2.Value = round(app.matrix19F(app.image19F_orient(app.OrientationSpinner.Value,1))/2);
                        
                    end
                    
                case '1H'
                    
                    % Make the 1H slice selectors visible
                    app.RawSliceSlider1H.Visible = 'on';
                    app.SliceEditField1H.Visible = 'on';
                    app.RawSliceSlider.Visible = 'off';
                    app.RawSliceSlider.Visible = 'off';
                    
                    if app.valid1Hfile
                        
                        % Set appropriate bounds for the slice selectors
                        app.SliceEditField1H.Value = 1;
                        app.RawSliceSlider1H.Value = 1;
                        app.SliceEditField1H.Limits = [1 app.matrix1H(app.image1H_orient(app.OrientationSpinner.Value,1))];
                        app.RawSliceSlider1H.Limits = [1 app.matrix1H(app.image1H_orient(app.OrientationSpinner.Value,1))];
                        app.SliceEditField1H.Value = round(app.matrix1H(app.image1H_orient(app.OrientationSpinner.Value,1))/2);
                        app.RawSliceSlider1H.Value = round(app.matrix1H(app.image1H_orient(app.OrientationSpinner.Value,1))/2);
                        
                    end
                    
            end
            
            % Check FOV
            if ~isequal(app.FOV1H,app.FOV19F)
                
                TextMessage(app, 'WARNING: FOVs do not match, overlays not possible !!');
                app.FOVmatch = false;
                
            else
                
                app.FOVmatch = true;
                
            end
            
            ShowAllFigures(app);
            
        end

        % Value changed function: RawReadoutEditField
        function RawReadoutEditFieldValueChanged(app, event)
            
            % show the images in the new readout orientation
            ShowAllFigures(app);
            
        end

        % Value changed function: SliceEditField
        function SliceEditFieldValueChanged(app, event)
            
            app.SliceEditField2.Value = app.SliceEditField.Value;
            app.RawSliceSlider.Value = app.SliceEditField.Value;
            app.RawSliceSlider2.Value = app.SliceEditField.Value;
            ShowAllFigures(app);
            
        end

        % Value changed function: SliceEditField2
        function SliceEditField2ValueChanged(app, event)
            
            app.SliceEditField.Value = app.SliceEditField2.Value;
            app.RawSliceSlider.Value = app.SliceEditField2.Value;
            app.RawSliceSlider2.Value = app.SliceEditField2.Value;
            ShowAllFigures(app);
            
        end

        % Value changed function: RawSliceSlider
        function RawSliceSliderValueChanged(app, event)
            
            app.SliceEditField.Value = round(app.RawSliceSlider.Value);
            app.SliceEditField2.Value = round(app.RawSliceSlider.Value);
            app.RawSliceSlider2.Value = app.RawSliceSlider.Value;
            ShowAllFigures(app);
            
        end

        % Value changed function: RawSliceSlider2
        function RawSliceSlider2ValueChanged(app, event)
            
            app.SliceEditField2.Value = round(app.RawSliceSlider2.Value);
            app.SliceEditField.Value = round(app.RawSliceSlider2.Value);
            app.RawSliceSlider.Value = app.RawSliceSlider2.Value;
            ShowAllFigures(app);
            
        end

        % Value changed function: SliceEditField1H
        function SliceEditField1HValueChanged(app, event)
            
            app.RawSliceSlider1H.Value = app.SliceEditField1H.Value;        % adjust slider according to edit field
            ShowAllFigures(app);                                            % show the images
            
        end

        % Value changed function: RawSliceSlider1H
        function RawSliceSlider1HValueChanged(app, event)
            
            app.SliceEditField1H.Value = round(app.RawSliceSlider1H.Value); % ajdust edit field according to slider
            ShowAllFigures(app);                                            % show the images
            
        end

        % Value changed function: OrientationSpinner
        function OrientationSpinnerValueChanged(app, event)
            
            app.OrientationSpinner2.Value = app.OrientationSpinner.Value;   % change both orientation spinners
            RawNucleusSwitchValueChanged(app, event);                       % make sure that slice index does not exceed bounds in other orienation
            
        end

        % Value changed function: OrientationSpinner2
        function OrientationSpinner2ValueChanged(app, event)
            
            app.OrientationSpinner.Value = app.OrientationSpinner2.Value;   % change both orientation spinners
            RawNucleusSwitchValueChanged(app, event);                       % make sure that slice index does not exceed bounds in other orienation
            
        end

        % Value changed function: GPUCheckBox, IterationsEditField, 
        % LambdaEditField, TransCorrCheckBox, ZeroFillCheckBox
        function ParametersCheckBoxValueChanged(app, event)
            
            % Load the data again to reset parameters
            
            if strcmp(app.brukerinfo1.Method,'fLASH_MPCS_19F')
                % read undersampled Bruker data
                app.R = app.R.loadBrukerFilesCS;
            else
                % read fully sampled Bruker data
                app.R = app.R.loadBrukerFiles;
            end
            
            SetParametersFcn(app);
            RawNucleusSwitchValueChanged(app, event);
            
            % Reset lights and buttons
            SetLights(app,{'Null','Null','Red','Red','Red','Red'});
            SetButtons(app,{'on','on','Null','Null','off','off','on'});
            
        end

        % Callback function
        function PeaksEditFieldValueChanged(app, event)
            
            app.R.P.empirical_alpha(1) = app.Peak1EditField.Value;
            app.R.P.empirical_alpha(2) = app.Peak2EditField.Value;
            app.R.P.empirical_alpha(3) = app.Peak3EditField.Value;
            app.R.P.empirical_alpha(4) = app.Peak4EditField.Value;
            app.R.P.empirical_alpha(5) = app.Peak5EditField.Value;
            CalculateSpectraFcn(app);
            
        end

        % Button pushed function: PseudoInverseButton
        function PseudoInverseButtonPushed(app, event)
            
            % Indicate busy
            SetLights(app,{'Null','Null','Yellow','Null','Null','Null'});
            SetButtons(app,{'off','off','off','off','off','off','off'});
            DisableEditFields(app);
            
            % Retrieve reconstruction input parameters from the app
            ParametersCheckBoxValueChanged(app, event);
            
            % Re-read the compound names, peak intensitities, and peak positions from file
            app.ReadCompoundsFcn;
            app.CalculateSpectraFcn;
            
            % Reset the progress Gauge
            app.ProgressGauge.Value = 0;
            
            % Prepare the data
            app.R = app.R.FixDoubleFOV;
            % app.R = app.R.TranslationCorr;
            app.R = app.R.ApplyCheckBoard;
            app.R = app.R.VectorizeData;
            app.R = app.R.PhaseRemoval;   % REAL IMAGE
            app.R.TestMeasOp;
            
            % Perform the Pseudo-inverse reconstruction
            app.R = app.R.PseudoInverse;
            
            % Show the result
            app.recodata{1} = squeeze(app.R.Recon{1,1});
            app.recodata{2} = squeeze(app.R.Recon{1,2});
            
            % Normalize the data to suitable scale for visualization in the app
            app.recodata{1} = round(4095*app.recodata{1}/max(app.recodata{1}(:)));
            app.recodata{2} = round(4095*app.recodata{2}/max(app.recodata{2}(:)));
            
            % Auto threshold and set colors
            app.CompoundSwitch.Value = app.c1name;
            app.CompoundSwitchValueChanged;
            app.ThresholdAutoButtonPushed;
            app.fluorButton.Value = 1;
            app.ColormapButtonGroupSelectionChanged;
            app.CompoundSwitch.Value = app.c2name;
            app.hotButton.Value = 1;
            app.ColormapButtonGroupSelectionChanged;
            app.CompoundSwitchValueChanged;
            app.ThresholdAutoButtonPushed;
            
            % Show the figures
            ShowAllFigures(app);
            
            % Reset buttons and lamps
            SetLights(app,{'Null','Null','Green','Null','Null','Null'});
            SetButtons(app,{'on','on','on','on','on','on','on'});
            EnableEditFields(app);
            
        end

        % Button pushed function: DeconvolutionButton
        function DeconvolutionButtonPushed(app, event)
            
            % Indicate busy
            SetLights(app,{'Null','Null','Null','Yellow','Null','Null'});
            SetButtons(app,{'off','off','off','off','off','off','off'});
            DisableEditFields(app);
            
            % Retrieve reconstruction input parameters from the app
            ParametersCheckBoxValueChanged(app, event);
            
            % Re-read the compound names, peak intensitities, and peak positions from file
            app.ReadCompoundsFcn;
            app.CalculateSpectraFcn;
            
            % Reset the progress Gauge
            app.ProgressGauge.Value = 0;
            
            % Prepare the reconstruction
            app.R=app.R.FixDoubleFOV;
            % app.R=app.R.TranslationCorr;
            app.R=app.R.ApplyCheckBoard;
            app.R=app.R.VectorizeData;
            app.R=app.R.PhaseRemoval;
            
            %% Perform the sparse CG recon
            if ~app.BlindDeconvolutionCheckBox.Value
                
                % Using 19F spectra
                app.R = app.R.ConjugateGradientRecon;
                
            else
                
                % Blind deconvolution
                app.R = app.R.LinRecon;
                app.R = app.R.BlindDeconvRecon;
                
            end
            
            % The resulting 19F reconstructions
            app.recodata{1} = squeeze(app.R.Recon{2,1});
            app.recodata{2} = squeeze(app.R.Recon{2,2});
            
            % Normalize the data to suitable scale for visualization in the app
            app.recodata{1} = round(4095*app.recodata{1}/max(app.recodata{1}(:)));
            app.recodata{2} = round(4095*app.recodata{2}/max(app.recodata{2}(:)));
            
            %% Auto threshold and set colors
            app.CompoundSwitch.Value = app.c1name;
            app.CompoundSwitchValueChanged;
            app.ThresholdAutoButtonPushed;
            app.fluorButton.Value = 1;
            app.ColormapButtonGroupSelectionChanged;
            app.CompoundSwitch.Value = app.c2name;
            app.hotButton.Value = 1;
            app.ColormapButtonGroupSelectionChanged;
            app.CompoundSwitchValueChanged;
            app.ThresholdAutoButtonPushed;
            
            % Show the figures
            ShowAllFigures(app);
            
            % Reset buttons and lamps
            SetLights(app,{'Null','Null','Null','Green','Null','Null'});
            SetButtons(app,{'on','on','on','on','on','on','on'});
            EnableEditFields(app);
            
        end

        % Value changed function: CompoundSwitch
        function CompoundSwitchValueChanged(app, event)
            
            if app.valid19Ffile
                
                switch app.CompoundSwitch.Value
                    
                    case app.c2name
                        
                        scalelimit = max(app.recodata{1}(:));
                        if scalelimit == 0 scalelimit = 4095; end
                        
                        app.ThresholdSlider.Limits = [0, scalelimit];
                        app.ThresholdEditField.Limits = [0, scalelimit];
                        app.ThresholdEditField.Value = app.threshold{1};
                        app.ThresholdSlider.Value = app.threshold{1};
                        
                        if app.cindx{1} == 1 app.grayButton.Value = 1; end
                        if app.cindx{1} == 2 app.fluorButton.Value = 1; end
                        if app.cindx{1} == 3 app.hotButton.Value = 1; end
                        if app.cindx{1} == 4 app.parulaButton.Value = 1; end
                        if app.cindx{1} == 5 app.summerButton.Value = 1; end
                        if app.cindx{1} == 6 app.winterButton.Value = 1; end
                        
                    case app.c1name
                        
                        scalelimit = max(app.recodata{2}(:));
                        if scalelimit == 0 scalelimit = 4095; end
                        
                        app.ThresholdSlider.Limits = [0, scalelimit];
                        app.ThresholdEditField.Limits = [0, scalelimit];
                        app.ThresholdEditField.Value = app.threshold{2};
                        app.ThresholdSlider.Value = app.threshold{2};
                        
                        if app.cindx{2} == 1 app.grayButton.Value = 1; end
                        if app.cindx{2} == 2 app.fluorButton.Value = 1; end
                        if app.cindx{2} == 3 app.hotButton.Value = 1; end
                        if app.cindx{2} == 4 app.parulaButton.Value = 1; end
                        if app.cindx{2} == 5 app.summerButton.Value = 1; end
                        if app.cindx{2} == 6 app.winterButton.Value = 1; end
                        
                end
                
            end
            
        end

        % Selection changed function: ColormapButtonGroup
        function ColormapButtonGroupSelectionChanged(app, event)
            
            switch app.CompoundSwitch.Value
                
                case app.c2name
                    indx = 1;
                    
                case app.c1name
                    indx = 2;
                    
            end
            
            if app.grayButton.Value == 1
                app.cindx{indx} = 1;
                app.cmap{indx} = gray;
            end
            
            if app.fluorButton.Value == 1
                app.cindx{indx} = 2;
                app.cmap{indx} = [linspace(0,1,64)', linspace(0.5,1,64)',linspace(0,1,64)'];
            end
            
            if app.hotButton.Value == 1
                app.cindx{indx} = 3;
                app.cmap{indx} = hot;
            end
            
            if app.parulaButton.Value == 1
                app.cindx{indx} = 4;
                app.cmap{indx} = parula;
            end
            
            if app.summerButton.Value == 1
                app.cindx{indx} = 5;
                app.cmap{indx} = summer;
            end
            
            if app.winterButton.Value == 1
                app.cindx{indx} = 6;
                app.cmap{indx} = winter;
            end
            
            app.cmap{indx}(1,:) = [0 0 0];
            
            ShowAllFigures(app);
            
        end

        % Button pushed function: ThresholdAutoButton
        function ThresholdAutoButtonPushed(app, event)
            
            % Auto threshold 19F images
            
            if app.valid19Ffile
                
                switch app.CompoundSwitch.Value
                    
                    case app.c2name
                        
                        im = app.recodata{1};
                        [counts,~] = imhist(mat2gray(im),256);
                        app.threshold{1} = otsuthresh(counts)*max(im(:));
                        if app.threshold{1} == 0 app.threshold{1}=1; end
                        
                        app.ThresholdEditField.Value = app.threshold{1};
                        app.ThresholdSlider.Value = app.threshold{1};
                        
                    case app.c1name
                        
                        im = app.recodata{2};
                        [counts,~] = imhist(mat2gray(im),256);
                        app.threshold{2} = otsuthresh(counts)*max(im(:));
                        
                        if app.threshold{2} == 0 app.threshold{2}=1; end
                        
                        app.ThresholdEditField.Value = app.threshold{2};
                        app.ThresholdSlider.Value = app.threshold{2};
                        
                end
                
            end
            
            ApplyThreshold(app);
            ShowAllFigures(app);
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            switch app.CompoundSwitch.Value
                
                case app.c2name
                    
                    app.threshold{1} = app.ThresholdEditField.Value;
                    
                case app.c1name
                    
                    app.threshold{2} = app.ThresholdEditField.Value;
                    
            end
            
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;
            
            ApplyThreshold(app);
            ShowAllFigures(app);
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            app.ThresholdEditField.Value = app.ThresholdSlider.Value;
            ThresholdEditFieldValueChanged(app, event);
            
        end

        % Button pushed function: ExportDicomButton
        function ExportDicomButtonPushed(app, event)
            
            % Indicate busy
            SetLights(app,{'Null','Null','Null','Null','Yellow','Null'});
            SetButtons(app,{'off','off','off','off','off','off','off'});
            DisableEditFields(app);
            
            % Ask for directory
            app.FluorUIFigure.Visible = 'off';
            app.dicom_export_path = uigetdir(app.dicom_export_path,'DICOM export path');
            app.FluorUIFigure.Visible = 'on';
            drawnow;
            
            % Check if directory exists
            ready = true;
            if ~ischar(app.dicom_export_path)   % user cancel
                
                ready = false;
                app.dicom_export_path = '';
                
            else
                
                [~, Attrib] = fileattrib(app.dicom_export_path);
                
                if ~any(Attrib.UserWrite)   % check if directory is writable
                    
                    ready = false;
                    
                end
                
            end
            
            % Export to DICOM
            if ready
                
                if app.valid1Hfile
                    
                    % set sequence parameters
                    app.seqpar.filename = 'Fluorine';
                    app.seqpar.SLICE_SEPARATION = app.FOV1H(1)/app.matrix1H(1);
                    app.seqpar.SLICE_THICKNESS = app.FOV1H(1)/app.matrix1H(1);
                    app.seqpar.SLICE_INTERLEAVE = 1;
                    app.seqpar.SMX = app.FOV1H(2)/app.matrix1H(2);
                    app.seqpar.SMY = app.FOV1H(3)/app.matrix1H(3);
                    app.seqpar.FOV = app.FOV1H(2);
                    app.seqpar.NO_SLICES = app.matrix1H(1);
                    app.seqpar.NO_VIEWS = app.matrix1H(3);
                    app.seqpar.tr = app.TR1H;
                    app.seqpar.te = app.TE1H;
                    app.seqpar.alpha = app.alpha1H;
                    app.seqpar.NO_ECHOES = 1;
                    app.seqpar.NO_AVERAGES = app.NA1H;
                    app.seqpar.date = datetime;
                    app.seqpar.nucleus = '1H';
                    app.seqpar.PPL = 'FLASH';
                    app.seqpar.filename = 111;
                    
                    % normalize to suitable range
                    im = round(32767 * app.image1H / max(app.image1H(:)));
                    
                    % export
                    export_dicom_19F(app.dicom_export_path,im,app.seqpar,'Proton');
                    
                end
                
                if app.valid19Ffile
                    
                    % set sequence parameters
                    app.seqpar.filename = 'Fluorine';
                    app.seqpar.SLICE_SEPARATION = app.FOV19F(1)/app.matrix19F(1);
                    app.seqpar.SLICE_THICKNESS = app.FOV19F(1)/app.matrix19F(1);
                    app.seqpar.SLICE_INTERLEAVE = 1;
                    app.seqpar.SMX = app.FOV19F(2)/app.matrix19F(2);
                    app.seqpar.SMY = app.FOV19F(3)/app.matrix19F(3);
                    app.seqpar.FOV = app.FOV19F(2);
                    app.seqpar.NO_SLICES = app.matrix19F(1);
                    app.seqpar.NO_VIEWS = app.matrix19F(3);
                    app.seqpar.tr = app.TR19F;
                    app.seqpar.te = app.TE19F;
                    app.seqpar.alpha = app.alpha19F;
                    app.seqpar.NO_ECHOES = 1;
                    app.seqpar.NO_AVERAGES = app.NA19F;
                    app.seqpar.date = datetime;
                    app.seqpar.nucleus = '19F';
                    app.seqpar.PPL = 'FLASH-MP';
                    
                    % normalize to suitable range
                    im = round(32767 * app.recodata{1} / max(max(app.recodata{1}(:),max(app.recodata{2}(:)))));
                    im = permute(im,[3,2,1]);
                    
                    % export C2
                    app.seqpar.filename = 112;
                    export_dicom_19F(app.dicom_export_path,im,app.seqpar,app.c2name);
                    
                    % normalize to suitable range
                    im = round(32767 * app.recodata{2} / max(max(app.recodata{1}(:),max(app.recodata{2}(:)))));
                    im = permute(im,[3,2,1]);
                    
                    % export C1
                    app.seqpar.filename = 113;
                    export_dicom_19F(app.dicom_export_path,im,app.seqpar,app.c1name);
                    
                    
                end
                
                SetLights(app,{'Null','Null','Null','Null','Green','Null'});
                
            else
                
                f = app.FluorUIFigure;
                uialert(f,'Not a valid directory','Error');
                SetLights(app,{'Null','Null','Null','Null','Red','Null'});
                
            end
            
            % Reset buttons and lamps
            SetButtons(app,{'on','on','on','on','on','on','on'});
            EnableEditFields(app);
            
            
        end

        % Button pushed function: ExportGifButton
        function ExportGifButtonPushed(app, event)
            
            % Indicate busy
            SetLights(app,{'Null','Null','Null','Null','Null','Yellow'});
            SetButtons(app,{'off','off','off','off','off','off','off'});
            DisableEditFields(app);
            
            % Ask for directory
            app.FluorUIFigure.Visible = 'off';
            app.gif_export_path = uigetdir(app.gif_export_path,'GIF export path');
            app.FluorUIFigure.Visible = 'on';
            drawnow;
            
            % Check if directory exists
            ready = true;
            if ~ischar(app.gif_export_path)   % user cancel
                
                ready = false;
                app.gif_export_path = '';
                
            else
                
                [~, Attrib] = fileattrib(app.gif_export_path);
                
                if ~any(Attrib.UserWrite)   % check if directory is writable
                    
                    ready = false;
                    
                end
                
            end
            
            % Export to animated gif
            if ready
                
                % C2
                im1 = app.recodata{1}.*squeeze(app.mask(1,:,:,:));
                im1 = permute(im1,[3,2,1]);
                im1 = permute(im1,app.image_orient(app.OrientationSpinner.Value,:));
                
                % C1
                im2 = app.recodata{2}.*squeeze(app.mask(2,:,:,:));
                im2 = permute(im2,[3,2,1]);
                im2 = permute(im2,app.image_orient(app.OrientationSpinner.Value,:));
                
                % Export 19F only
                export_gif_19F(app,app.gif_export_path,'multicolor',im2,im1,app.cmap{2},app.cmap{1});
                
                SetLights(app,{'Null','Null','Null','Null','Null','Green'});
                
            else
                
                f = app.FluorUIFigure;
                uialert(f,'Not a valid directory','Error');
                SetLights(app,{'Null','Null','Null','Null','Null','Red'});
                
            end
            
            % Reset buttons
            SetButtons(app,{'on','on','on','on','on','on','on'});
            EnableEditFields(app);
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            % Remove subfolders from search path
            if (~isdeployed)
                
                info = dir(which('multicolor19F.mlapp'));
                app.appdir = info.folder;
                rmpath(genpath(app.appdir));
                cd(app.olddir);
                
            end
            
            % Exit
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FluorUIFigure = uifigure('Visible', 'off');
            app.DashboardPanel = uipanel(app.FluorUIFigure);
            app.DashboardPanel.Title = 'Dashboard';
            app.ImportdataPanel = uipanel(app.DashboardPanel);
            app.ImportdataPanel.Title = 'Import data';
            app.Load19FDataButton = uibutton(app.ImportdataPanel, 'push');
            app.Load19FDataButton.ButtonPushedFcn = createCallbackFcn(app, @Load19FDataButtonPushed, true);
            app.Load19FDataButton.Text = 'Load 19F data';
            app.Lamp1 = uilamp(app.ImportdataPanel);
            app.MRSolutionsCheckBox = uicheckbox(app.ImportdataPanel);
            app.MRSolutionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MRSolutionsCheckBoxValueChanged, true);
            app.MRSolutionsCheckBox.Text = 'MR Solutions';
            app.BrukerCheckBox = uicheckbox(app.ImportdataPanel);
            app.BrukerCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrukerCheckBoxValueChanged, true);
            app.BrukerCheckBox.Text = 'Bruker';
            app.BrukerCheckBox.Value = true;
            app.Lamp2 = uilamp(app.ImportdataPanel);
            app.Load1HDataButton = uibutton(app.ImportdataPanel, 'push');
            app.Load1HDataButton.ButtonPushedFcn = createCallbackFcn(app, @Load1HDataButtonPushed, true);
            app.Load1HDataButton.Text = 'Load 1H data';
            app.UseTestDataCheckBox = uicheckbox(app.ImportdataPanel);
            app.UseTestDataCheckBox.Text = 'Use Test Data';
            app.ReconstructionPanel = uipanel(app.DashboardPanel);
            app.ReconstructionPanel.Title = 'Reconstruction';
            app.DeconvolutionButton = uibutton(app.ReconstructionPanel, 'push');
            app.DeconvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @DeconvolutionButtonPushed, true);
            app.DeconvolutionButton.Enable = 'off';
            app.DeconvolutionButton.Text = 'Deconvolution';
            app.Lamp3 = uilamp(app.ReconstructionPanel);
            app.Lamp4 = uilamp(app.ReconstructionPanel);
            app.PseudoInverseButton = uibutton(app.ReconstructionPanel, 'push');
            app.PseudoInverseButton.ButtonPushedFcn = createCallbackFcn(app, @PseudoInverseButtonPushed, true);
            app.PseudoInverseButton.Enable = 'off';
            app.PseudoInverseButton.Text = 'PseudoInverse';
            app.ProgressGaugeLabel = uilabel(app.ReconstructionPanel);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.ReconstructionPanel, 'linear');
            app.ExportdataPanel = uipanel(app.DashboardPanel);
            app.ExportdataPanel.Title = 'Export data';
            app.ExportDicomButton = uibutton(app.ExportdataPanel, 'push');
            app.ExportDicomButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDicomButtonPushed, true);
            app.ExportDicomButton.Enable = 'off';
            app.ExportDicomButton.Text = 'Export Dicom';
            app.ExportGifButton = uibutton(app.ExportdataPanel, 'push');
            app.ExportGifButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGifButtonPushed, true);
            app.ExportGifButton.Enable = 'off';
            app.ExportGifButton.Text = 'Export Gif';
            app.Lamp5 = uilamp(app.ExportdataPanel);
            app.Lamp6 = uilamp(app.ExportdataPanel);
            app.Panel = uipanel(app.DashboardPanel);
            app.ExitButton = uibutton(app.Panel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.RawImageDataPanel = uipanel(app.FluorUIFigure);
            app.RawImageDataPanel.Title = 'Raw image data';
            app.DataselectionPanel = uipanel(app.RawImageDataPanel);
            app.DataselectionPanel.Title = 'Data selection';
            app.RawNucleusSwitch = uiswitch(app.DataselectionPanel, 'slider');
            app.RawNucleusSwitch.Items = {'19F', '1H'};
            app.RawNucleusSwitch.ValueChangedFcn = createCallbackFcn(app, @RawNucleusSwitchValueChanged, true);
            app.RawNucleusSwitch.Value = '19F';
            app.ReadoutSpinnerLabel = uilabel(app.DataselectionPanel);
            app.ReadoutSpinnerLabel.Text = 'Readout';
            app.RawReadoutEditField = uispinner(app.DataselectionPanel);
            app.RawReadoutEditField.Limits = [1 4];
            app.RawReadoutEditField.ValueChangedFcn = createCallbackFcn(app, @RawReadoutEditFieldValueChanged, true);
            app.RawReadoutEditField.Value = 1;
            app.ImageselectionPanel = uipanel(app.RawImageDataPanel);
            app.ImageselectionPanel.Title = 'Image selection';
            app.RawSliceSlider = uislider(app.ImageselectionPanel);
            app.RawSliceSlider.Limits = [1 32];
            app.RawSliceSlider.MajorTicks = [];
            app.RawSliceSlider.ValueChangedFcn = createCallbackFcn(app, @RawSliceSliderValueChanged, true);
            app.RawSliceSlider.MinorTicks = [];
            app.RawSliceSlider.Value = 1;
            app.SliceEditFieldLabel = uilabel(app.ImageselectionPanel);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SliceEditField = uieditfield(app.ImageselectionPanel, 'numeric');
            app.SliceEditField.Limits = [1 32];
            app.SliceEditField.ValueDisplayFormat = '%.0f';
            app.SliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceEditFieldValueChanged, true);
            app.SliceEditField.Value = 1;
            app.OrientationSpinnerLabel = uilabel(app.ImageselectionPanel);
            app.OrientationSpinnerLabel.Text = 'Orientation';
            app.OrientationSpinner = uispinner(app.ImageselectionPanel);
            app.OrientationSpinner.Limits = [1 3];
            app.OrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @OrientationSpinnerValueChanged, true);
            app.OrientationSpinner.Value = 1;
            app.SliceEditField_2Label_2 = uilabel(app.ImageselectionPanel);
            app.SliceEditField_2Label_2.Visible = 'off';
            app.SliceEditField_2Label_2.Text = 'Slice';
            app.SliceEditField1H = uieditfield(app.ImageselectionPanel, 'numeric');
            app.SliceEditField1H.Limits = [1 32];
            app.SliceEditField1H.ValueDisplayFormat = '%.0f';
            app.SliceEditField1H.ValueChangedFcn = createCallbackFcn(app, @SliceEditField1HValueChanged, true);
            app.SliceEditField1H.Visible = 'off';
            app.SliceEditField1H.Value = 1;
            app.RawSliceSlider1H = uislider(app.ImageselectionPanel);
            app.RawSliceSlider1H.Limits = [1 32];
            app.RawSliceSlider1H.MajorTicks = [];
            app.RawSliceSlider1H.ValueChangedFcn = createCallbackFcn(app, @RawSliceSlider1HValueChanged, true);
            app.RawSliceSlider1H.MinorTicks = [];
            app.RawSliceSlider1H.Visible = 'off';
            app.RawSliceSlider1H.Value = 1;
            app.YLabel = uilabel(app.RawImageDataPanel);
            app.YLabel.Text = 'Y';
            app.XLabel = uilabel(app.RawImageDataPanel);
            app.XLabel.Text = 'X';
            app.RawImagesFig = uiaxes(app.RawImageDataPanel);
            title(app.RawImagesFig, '')
            xlabel(app.RawImagesFig, '')
            ylabel(app.RawImagesFig, '')
            app.RawImagesFig.XTick = [];
            app.RawImagesFig.YTick = [];
            app.AcquisitionparametersPanel = uipanel(app.FluorUIFigure);
            app.AcquisitionparametersPanel.Title = 'Acquisition parameters';
            app.FMRIPanel = uipanel(app.AcquisitionparametersPanel);
            app.FMRIPanel.Title = '19F-MRI';
            app.MatrixEditFieldLabel = uilabel(app.FMRIPanel);
            app.MatrixEditFieldLabel.Text = 'Matrix';
            app.NxViewField = uieditfield(app.FMRIPanel, 'numeric');
            app.NxViewField.Editable = 'off';
            app.NyViewField = uieditfield(app.FMRIPanel, 'numeric');
            app.NyViewField.Editable = 'off';
            app.NzViewField = uieditfield(app.FMRIPanel, 'numeric');
            app.NzViewField.Editable = 'off';
            app.BWHzEditFieldLabel = uilabel(app.FMRIPanel);
            app.BWHzEditFieldLabel.Text = 'BW (Hz)';
            app.BWHzViewField = uieditfield(app.FMRIPanel, 'numeric');
            app.BWHzViewField.ValueDisplayFormat = '%.0f';
            app.BWHzViewField.Editable = 'off';
            app.BWpixelHzLabel = uilabel(app.FMRIPanel);
            app.BWpixelHzLabel.Text = 'BW/pixel (Hz)';
            app.BWpixelViewField = uieditfield(app.FMRIPanel, 'numeric');
            app.BWpixelViewField.Editable = 'off';
            app.FOVyViewField = uieditfield(app.FMRIPanel, 'numeric');
            app.FOVyViewField.Editable = 'off';
            app.FOVzViewField = uieditfield(app.FMRIPanel, 'numeric');
            app.FOVzViewField.Editable = 'off';
            app.FOVEditFieldLabel = uilabel(app.FMRIPanel);
            app.FOVEditFieldLabel.Text = 'FOV';
            app.FOVxViewField = uieditfield(app.FMRIPanel, 'numeric');
            app.FOVxViewField.Editable = 'off';
            app.ReadoutsLabel = uilabel(app.FMRIPanel);
            app.ReadoutsLabel.Text = 'Readouts';
            app.NrReadoutsViewField = uieditfield(app.FMRIPanel, 'numeric');
            app.NrReadoutsViewField.Limits = [1 16];
            app.NrReadoutsViewField.RoundFractionalValues = 'on';
            app.NrReadoutsViewField.ValueDisplayFormat = '%.0f';
            app.NrReadoutsViewField.Editable = 'off';
            app.NrReadoutsViewField.Value = 1;
            app.X19FLabel = uilabel(app.FMRIPanel);
            app.X19FLabel.Text = 'X';
            app.Y19FLabel = uilabel(app.FMRIPanel);
            app.Y19FLabel.Text = 'Y';
            app.Z19FLabel = uilabel(app.FMRIPanel);
            app.Z19FLabel.Text = 'Z';
            app.HMRIPanel = uipanel(app.AcquisitionparametersPanel);
            app.HMRIPanel.Title = '1H-MRI';
            app.Ny1HViewField = uieditfield(app.HMRIPanel, 'numeric');
            app.Ny1HViewField.Editable = 'off';
            app.Nz1HViewField = uieditfield(app.HMRIPanel, 'numeric');
            app.Nz1HViewField.Editable = 'off';
            app.FOVy1HViewField = uieditfield(app.HMRIPanel, 'numeric');
            app.FOVy1HViewField.Editable = 'off';
            app.FOVz1HViewField = uieditfield(app.HMRIPanel, 'numeric');
            app.FOVz1HViewField.Editable = 'off';
            app.FOV1HViewFieldLabel = uilabel(app.HMRIPanel);
            app.FOV1HViewFieldLabel.Text = 'FOV';
            app.FOVx1HViewField = uieditfield(app.HMRIPanel, 'numeric');
            app.FOVx1HViewField.Editable = 'off';
            app.MatrixEditField_2Label = uilabel(app.HMRIPanel);
            app.MatrixEditField_2Label.Text = 'Matrix';
            app.Nx1HViewField = uieditfield(app.HMRIPanel, 'numeric');
            app.Nx1HViewField.Editable = 'off';
            app.TRmsLabel = uilabel(app.HMRIPanel);
            app.TRmsLabel.Text = 'TR (ms)';
            app.TR1HViewField = uieditfield(app.HMRIPanel, 'numeric');
            app.TR1HViewField.Limits = [1 5000];
            app.TR1HViewField.RoundFractionalValues = 'on';
            app.TR1HViewField.Editable = 'off';
            app.TR1HViewField.Value = 1;
            app.TEmsLabel = uilabel(app.HMRIPanel);
            app.TEmsLabel.Text = 'TE (ms)';
            app.TE1HViewField = uieditfield(app.HMRIPanel, 'numeric');
            app.TE1HViewField.Limits = [1 50];
            app.TE1HViewField.RoundFractionalValues = 'on';
            app.TE1HViewField.Editable = 'off';
            app.TE1HViewField.Value = 1;
            app.X1HLabel = uilabel(app.HMRIPanel);
            app.X1HLabel.Text = 'X';
            app.Y1HLabel = uilabel(app.HMRIPanel);
            app.Y1HLabel.Text = 'Y';
            app.Z1HLabel = uilabel(app.HMRIPanel);
            app.Z1HLabel.Text = 'Z';
            app.VersionLabel = uilabel(app.FluorUIFigure);
            app.VersionLabel.Text = 'Amsterdam UMC, Jasper Schoormans & Gustav Strijkers, Aug 2019, Version 1.1';
            app.ReconstructionsPanel = uipanel(app.FluorUIFigure);
            app.ReconstructionsPanel.Title = 'Reconstructions';
            app.C1Fig = uiaxes(app.ReconstructionsPanel);
            title(app.C1Fig, '')
            xlabel(app.C1Fig, '')
            ylabel(app.C1Fig, '')
            app.C1Fig.XTick = [];
            app.C1Fig.YTick = [];
            app.C2Fig = uiaxes(app.ReconstructionsPanel);
            title(app.C2Fig, '')
            xlabel(app.C2Fig, '')
            ylabel(app.C2Fig, '')
            app.C2Fig.XTick = [];
            app.C2Fig.YTick = [];
            app.ImageSelectionPanel = uipanel(app.ReconstructionsPanel);
            app.ImageSelectionPanel.Title = 'Image Selection';
            app.RawSliceSlider2 = uislider(app.ImageSelectionPanel);
            app.RawSliceSlider2.Limits = [1 32];
            app.RawSliceSlider2.MajorTicks = [];
            app.RawSliceSlider2.ValueChangedFcn = createCallbackFcn(app, @RawSliceSlider2ValueChanged, true);
            app.RawSliceSlider2.MinorTicks = [];
            app.RawSliceSlider2.Value = 1;
            app.SliceEditField_2Label = uilabel(app.ImageSelectionPanel);
            app.SliceEditField_2Label.Text = 'Slice';
            app.SliceEditField2 = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.SliceEditField2.Limits = [1 32];
            app.SliceEditField2.ValueDisplayFormat = '%.0f';
            app.SliceEditField2.ValueChangedFcn = createCallbackFcn(app, @SliceEditField2ValueChanged, true);
            app.SliceEditField2.Value = 1;
            app.OrientationSpinner_2Label = uilabel(app.ImageSelectionPanel);
            app.OrientationSpinner_2Label.Text = 'Orientation';
            app.OrientationSpinner2 = uispinner(app.ImageSelectionPanel);
            app.OrientationSpinner2.Limits = [1 3];
            app.OrientationSpinner2.ValueChangedFcn = createCallbackFcn(app, @OrientationSpinner2ValueChanged, true);
            app.OrientationSpinner2.Value = 1;
            app.ThresholdPanel = uipanel(app.ReconstructionsPanel);
            app.ThresholdPanel.Title = 'Threshold';
            app.ThresholdEditFieldLabel = uilabel(app.ThresholdPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ThresholdPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 1024];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdAutoButton = uibutton(app.ThresholdPanel, 'push');
            app.ThresholdAutoButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdAutoButtonPushed, true);
            app.ThresholdAutoButton.Text = 'Auto';
            app.ThresholdSlider = uislider(app.ThresholdPanel);
            app.ThresholdSlider.Limits = [0 1024];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.CompoundSwitch = uiswitch(app.ReconstructionsPanel, 'slider');
            app.CompoundSwitch.Items = {'C1', 'C2'};
            app.CompoundSwitch.ValueChangedFcn = createCallbackFcn(app, @CompoundSwitchValueChanged, true);
            app.CompoundSwitch.Value = 'C1';
            app.ColormapButtonGroup = uibuttongroup(app.ReconstructionsPanel);
            app.ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroupSelectionChanged, true);
            app.ColormapButtonGroup.Title = 'Color map';
            app.grayButton = uiradiobutton(app.ColormapButtonGroup);
            app.grayButton.Text = 'gray';
            app.grayButton.Value = true;
            app.fluorButton = uiradiobutton(app.ColormapButtonGroup);
            app.fluorButton.Text = 'fluor';
            app.hotButton = uiradiobutton(app.ColormapButtonGroup);
            app.hotButton.Text = 'hot';
            app.parulaButton = uiradiobutton(app.ColormapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.summerButton = uiradiobutton(app.ColormapButtonGroup);
            app.summerButton.Text = 'summer';
            app.winterButton = uiradiobutton(app.ColormapButtonGroup);
            app.winterButton.Text = 'winter';
            app.SparseDeconvolutionPanel = uipanel(app.FluorUIFigure);
            app.SparseDeconvolutionPanel.Title = 'Sparse deconvolution';
            app.ParametersPanel = uipanel(app.SparseDeconvolutionPanel);
            app.ParametersPanel.Title = 'Parameters';
            app.ZeroFillCheckBox = uicheckbox(app.ParametersPanel);
            app.ZeroFillCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParametersCheckBoxValueChanged, true);
            app.ZeroFillCheckBox.Enable = 'off';
            app.ZeroFillCheckBox.Tooltip = {''};
            app.ZeroFillCheckBox.Text = '';
            app.TransCorrCheckBox = uicheckbox(app.ParametersPanel);
            app.TransCorrCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParametersCheckBoxValueChanged, true);
            app.TransCorrCheckBox.Enable = 'off';
            app.TransCorrCheckBox.Text = '';
            app.TransCorrCheckBox.Value = true;
            app.ZeroFillLabel = uilabel(app.ParametersPanel);
            app.ZeroFillLabel.Text = 'Zero-fill';
            app.TransCorrLabel = uilabel(app.ParametersPanel);
            app.TransCorrLabel.Text = 'Registration';
            app.LambdaEditFieldLabel = uilabel(app.ParametersPanel);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.LambdaEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.LambdaEditField.Limits = [0 Inf];
            app.LambdaEditField.ValueChangedFcn = createCallbackFcn(app, @ParametersCheckBoxValueChanged, true);
            app.LambdaEditField.Enable = 'off';
            app.LambdaEditField.Value = 100;
            app.IterationsEditFieldLabel = uilabel(app.ParametersPanel);
            app.IterationsEditFieldLabel.Text = '# Iterations';
            app.IterationsEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.IterationsEditField.Limits = [1 100];
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @ParametersCheckBoxValueChanged, true);
            app.IterationsEditField.Enable = 'off';
            app.IterationsEditField.Value = 15;
            app.GPUCheckBox = uicheckbox(app.ParametersPanel);
            app.GPUCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParametersCheckBoxValueChanged, true);
            app.GPUCheckBox.Enable = 'off';
            app.GPUCheckBox.Text = '';
            app.GPULabel = uilabel(app.ParametersPanel);
            app.GPULabel.Text = 'GPU';
            app.BlindDeconvolutionCheckBox = uicheckbox(app.ParametersPanel);
            app.BlindDeconvolutionCheckBox.Enable = 'off';
            app.BlindDeconvolutionCheckBox.Text = '';
            app.BlinddeconvLabel = uilabel(app.ParametersPanel);
            app.BlinddeconvLabel.Text = 'Blind deconv';
            app.FcompoundsLabel = uilabel(app.ParametersPanel);
            app.FcompoundsLabel.Text = '19F-compounds';
            app.CompoundBox = uitextarea(app.ParametersPanel);
            app.CompoundBox.Editable = 'off';
            app.DeconvolutionspectraPanel = uipanel(app.SparseDeconvolutionPanel);
            app.DeconvolutionspectraPanel.Title = 'Deconvolution spectra';
            app.C1SpectrumFig = uiaxes(app.DeconvolutionspectraPanel);
            title(app.C1SpectrumFig, '')
            xlabel(app.C1SpectrumFig, '')
            ylabel(app.C1SpectrumFig, '')
            app.C1SpectrumFig.YTick = [];
            app.C1SpectrumLabel = uilabel(app.DeconvolutionspectraPanel);
            app.C1SpectrumLabel.Text = '                     C1';
            app.C2SpectrumFig = uiaxes(app.DeconvolutionspectraPanel);
            title(app.C2SpectrumFig, '')
            xlabel(app.C2SpectrumFig, '')
            ylabel(app.C2SpectrumFig, '')
            app.C2SpectrumFig.YTick = [];
            app.C2SpectrumLabel = uilabel(app.DeconvolutionspectraPanel);
            app.C2SpectrumLabel.Text = '                     C2';
            app.TitlePanel = uilabel(app.FluorUIFigure);
            app.TitlePanel.Text = 'Two-color 19F-MRI reconstructor';
            app.Image = uiimage(app.FluorUIFigure);
            app.Image.ImageSource = 'AmsterdamUMC.gif';
            app.MessagesPanel = uipanel(app.FluorUIFigure);
            app.MessagesPanel.Title = 'Messages';
            app.MessageBox = uitextarea(app.MessagesPanel);
            app.MessageBox.Editable = 'off';
            % Show the figure after all components are created
            app.FluorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multicolor19F

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FluorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FluorUIFigure)
        end
    end
end